classdef project2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FinalProjectCadenSandersUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        OpenImageButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Image1Tab                       matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        TabGroup_2                      matlab.ui.container.TabGroup
        Image1Tab_2                     matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        CurrentLabel                    matlab.ui.control.Label
        PreviewLabel                    matlab.ui.control.Label
        SaveResultButton                matlab.ui.control.Button
        GeneratePreviewButton           matlab.ui.control.Button
        ApplyChangesButton              matlab.ui.control.Button
        SpatialDomainFilterOptionsDropDownLabel  matlab.ui.control.Label
        SpatialDomainFilterOptionsDropDown  matlab.ui.control.DropDown
        NeighborhoodSizeButtonGroup     matlab.ui.container.ButtonGroup
        x3Button                        matlab.ui.control.RadioButton
        x5Button                        matlab.ui.control.RadioButton
        x7Button                        matlab.ui.control.RadioButton
        x9Button                        matlab.ui.control.RadioButton
        BrightnessSliderLabel           matlab.ui.control.Label
        BrightnessSlider                matlab.ui.control.Slider
        ContrastSliderLabel             matlab.ui.control.Label
        ContrastSlider                  matlab.ui.control.Slider
        FrequencyDomainFilterOptionsDropDownLabel  matlab.ui.control.Label
        FrequencyDomainFilterOptionsDropDown  matlab.ui.control.DropDown
        FilterDomainButtonGroup         matlab.ui.container.ButtonGroup
        SpatialDomainButton             matlab.ui.control.RadioButton
        FrequencyDomainButton           matlab.ui.control.RadioButton
        MorphologicalFiltersButton      matlab.ui.control.RadioButton
        BoostScalingCoefficientEditFieldLabel  matlab.ui.control.Label
        BoostScalingCoefficientEditField  matlab.ui.control.NumericEditField
        FilterTypeDropDownLabel         matlab.ui.control.Label
        FilterTypeDropDown              matlab.ui.control.DropDown
        ButterworthFilterOrderDropDownLabel  matlab.ui.control.Label
        ButterworthFilterOrderDropDown  matlab.ui.control.DropDown
        FirstCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        FirstCutoffFrequencyEditField   matlab.ui.control.NumericEditField
        SecondCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        SecondCutoffFrequencyEditField  matlab.ui.control.NumericEditField
        MorphologicalFilterOptionsLabel  matlab.ui.control.Label
        MorphilogicalFilterOptionsDropDown  matlab.ui.control.DropDown
        ColorSpaceDropDownLabel         matlab.ui.control.Label
        ColorSpaceDropDown              matlab.ui.control.DropDown
        RedMinimumEditFieldLabel        matlab.ui.control.Label
        RedMinimumEditField             matlab.ui.control.NumericEditField
        RedMaximumEditFieldLabel        matlab.ui.control.Label
        RedMaximumEditField             matlab.ui.control.NumericEditField
        GreenMinimumEditFieldLabel      matlab.ui.control.Label
        GreenMinimumEditField           matlab.ui.control.NumericEditField
        GreenMaximumEditFieldLabel      matlab.ui.control.Label
        GreenMaximumEditField           matlab.ui.control.NumericEditField
        BlueMinimumEditFieldLabel       matlab.ui.control.Label
        BlueMinimumEditField            matlab.ui.control.NumericEditField
        BlueMaximumEditFieldLabel       matlab.ui.control.Label
        BlueMaximumEditField            matlab.ui.control.NumericEditField
        HueMinimumEditFieldLabel        matlab.ui.control.Label
        HueMinimumEditField             matlab.ui.control.NumericEditField
        HueMaximumEditFieldLabel        matlab.ui.control.Label
        HueMaximumEditField             matlab.ui.control.NumericEditField
        SaturationMinimumEditFieldLabel  matlab.ui.control.Label
        SaturationMinimumEditField      matlab.ui.control.NumericEditField
        SaturationMaximumEditFieldLabel  matlab.ui.control.Label
        SaturationMaximumEditField      matlab.ui.control.NumericEditField
        ValueMaximumEditFieldLabel      matlab.ui.control.Label
        ValueMaximumEditField           matlab.ui.control.NumericEditField
        ValueMinimumEditFieldLabel      matlab.ui.control.Label
        ValueMinimumEditField           matlab.ui.control.NumericEditField
        StructuringElementButtonGroup   matlab.ui.container.ButtonGroup
        SquareButton                    matlab.ui.control.RadioButton
        RectangleButton                 matlab.ui.control.RadioButton
        CircleDiskButton                matlab.ui.control.RadioButton
        StructuringElementSizeButtonGroup  matlab.ui.container.ButtonGroup
        SmallButton                     matlab.ui.control.ToggleButton
        MediumButton                    matlab.ui.control.ToggleButton
        LargeButton                     matlab.ui.control.ToggleButton
        GammaLowEditFieldLabel          matlab.ui.control.Label
        GammaLowEditField               matlab.ui.control.NumericEditField
        GammaHighEditFieldLabel         matlab.ui.control.Label
        GammaHighEditField              matlab.ui.control.NumericEditField
    end

    properties (Access = public)
        imageData % The original image file data
        fileName
        filterDomain % Specifies what domain the filtering is done in
        filterOption % Specifies what kind of filter is being used
        filterType % Specifies the frequency domain filter type
        brightness 
        contrast 
        highboostScale
        thresh
        order
        preview
        thresh2 
        colorSpace
        structEl
        squareStructSize
        rectangleStructSize
        circleStructSize
        gammaHigh
        gammaLow
    end

    methods (Access = private)
    
        function mycallback(app,src,event)
            display(event.SelectedOption);
        end%mycallback ends
        
    end
    

    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            
            %Reads the file in with the following file types
            [file, app.fileName] = uigetfile( ...
                {'*.png;*.jpg;*.bmp',...
                'Image Files (*.png;*.jpg;*.bmp)';
                '*.*',  'All Files (*.*)'}, ...
                'Select an Image');
            
            %Display an error if the file can't be read
            if file==0
                message = sprintf('Sorry! \n There was an error.');
                uialert(errfig,message,'Error',...
                    'Icon','warning');
            
            %The file has succesfully been read
            else
                %Get image data
                app.imageData = imread(file);
                
                %Clear the axes
                cla(app.UIAxes,'reset')
                
                %Display the image with some axes settings
                imshow(app.imageData,'Parent',app.UIAxes);
                app.UIAxes.XLabel.Visible = 'off';
                app.UIAxes.YLabel.Visible = 'off';
                app.UIAxes.Title.Visible = 'off';
                app.UIAxes.XLim = [0 inf];
                app.UIAxes.YLim = [0 inf];
                
                %Make sure all the variables and properties are set
                app.filterDomain = app.FilterDomainButtonGroup.SelectedObject;
                
                %filter domain switch starting point
                switch app.filterDomain
                    
                    %filter domain case 1
                    case app.FrequencyDomainButton
                        app.filterOption = app.SpatialDomainFilterOptionsDropDown.Value;
                    
                    %filter domain case 2
                    case app.SpatialDomainButton
                        app.filterOption = app.FrequencyDomainFilterOptionsDropDown.Value;
                    
                    %filter domain case 3
                    case app.MorphologicalFiltersButton
                        app.filterOption = app.MorphilogicalFilterOptionsDropDown.Value;
                
                end%filter domain switch ends
                
                app.filterType = app.FilterTypeDropDown.Value;
                app.highboostScale = app.BoostScalingCoefficientEditField.Value;
                app.brightness = app.BrightnessSlider.Value;
                app.contrast = app.ContrastSlider.Value;
                app.thresh = app.FirstCutoffFrequencyEditField.Value;
                app.order = app.ButterworthFilterOrderDropDown.Value;
                app.thresh2 = app.SecondCutoffFrequencyEditField.Value;
                app.filterOption = app.MorphilogicalFilterOptionsDropDown.Value;
                app.colorSpace = app.ColorSpaceDropDown.Value;
                app.structEl = app.StructuringElementButtonGroup.SelectedObject;
                app.squareStructSize = 5;
                app.rectangleStructSize = [5 10];
                app.circleStructSize = 4;
                app.gammaHigh = app.GammaHighEditField.Value;
                app.gammaLow = app.GammaLowEditField.Value;
                
            end%ifelse ends
            
        end

        % Value changed function: SpatialDomainFilterOptionsDropDown
        function SpatialDomainFilterOptionsDropDownValueChanged(app, event)
            %Set the filter option
            app.filterOption = app.SpatialDomainFilterOptionsDropDown.Value;
            
            %filter option switch starting point
            switch app.filterOption
                
                %filter option case 1
                case 'None'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                
                %filter option case 2
                case 'Brightness'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'on';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'on';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off'
                
                %filter option case 3
                case 'Contrast'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'on';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'on';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                
                %filter option case 4
                case 'Smoothing Low-Pass'
                    app.NeighborhoodSizeButtonGroup.Visible = 'on';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                
                %filter option case 5
                case 'Edge Detect High-Pass'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                
                %filter option case 6
                case 'Highboost'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'on';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'on';
                
                %filter option case 7
                case 'Global Histogram-Equalization'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                
                %filter option case 8
                case 'Adaptive Histogram-Equalization'
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    
            end%filter option switch ends
            
        end

        % Selection changed function: FilterDomainButtonGroup
        function FilterDomainButtonGroupSelectionChanged(app, event)
            %set the filter domain
            app.filterDomain = app.FilterDomainButtonGroup.SelectedObject;
            
            %filter domain switch starting point
            switch app.filterDomain
                
                %filter domain case 1
                case app.SpatialDomainButton
                    
                    %turn spatial domain visuals on and set to none
                    app.SpatialDomainFilterOptionsDropDown.Value = 'None';
                    app.SpatialDomainFilterOptionsDropDown.Visible = 'on';
                    app.SpatialDomainFilterOptionsDropDownLabel.Visible = 'on';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    
                    %turn frequency domain visuals off
                    app.FrequencyDomainFilterOptionsDropDown.Visible = 'off';
                    app.FrequencyDomainFilterOptionsDropDownLabel.Visible = 'off';
                    app.FilterTypeDropDown.Visible = 'off';
                    app.FilterTypeDropDownLabel.Visible = 'off';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.FirstCutoffFrequencyEditField.Visible = 'off';
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.SecondCutoffFrequencyEditField.Visible = 'off';
                    
                    %turn morphological filter visuals off
                    app.MorphilogicalFilterOptionsDropDown.Visible = 'off';
                    app.MorphologicalFilterOptionsLabel.Visible = 'off';
                    app.ColorSpaceDropDown.Visible = 'off';
                    app.ColorSpaceDropDownLabel.Visible = 'off';
                    app.StructuringElementButtonGroup.Visible = 'off';
                    
                    %turn rgb option visuals off
                    app.BlueMinimumEditField.Visible = 'off';
                    app.BlueMinimumEditFieldLabel.Visible = 'off';
                    app.GreenMinimumEditField.Visible = 'off';
                    app.GreenMinimumEditFieldLabel.Visible = 'off';
                    app.RedMinimumEditField.Visible = 'off';
                    app.RedMinimumEditFieldLabel.Visible = 'off';
                    app.BlueMaximumEditField.Visible = 'off';
                    app.BlueMaximumEditFieldLabel.Visible = 'off';
                    app.GreenMaximumEditField.Visible = 'off';
                    app.GreenMaximumEditFieldLabel.Visible = 'off';
                    app.RedMaximumEditField.Visible = 'off';
                    app.RedMaximumEditFieldLabel.Visible = 'off';
                    
                    %Turn hsv option visuals off
                    app.HueMinimumEditFieldLabel.Visible = 'off';
                    app.HueMinimumEditField.Visible = 'off';
                    app.HueMaximumEditFieldLabel.Visible = 'off';
                    app.HueMaximumEditField.Visible = 'off';
                    app.SaturationMinimumEditFieldLabel.Visible = 'off';
                    app.SaturationMinimumEditField.Visible = 'off';
                    app.SaturationMaximumEditFieldLabel.Visible = 'off';
                    app.SaturationMaximumEditField.Visible = 'off';
                    app.ValueMaximumEditFieldLabel.Visible = 'off';
                    app.ValueMaximumEditField.Visible = 'off';
                    app.ValueMinimumEditFieldLabel.Visible = 'off';
                    app.ValueMinimumEditField.Visible = 'off';
                    
                    %turn structuring element size visual off
                    app.StructuringElementSizeButtonGroup.Visible = 'off';
                    
                    %turn gamma visuals off
                    app.GammaLowEditField.Visible = 'off';
                    app.GammaLowEditFieldLabel.Visible = 'off';
                    app.GammaHighEditField.Visible = 'off';
                    app.GammaHighEditFieldLabel.Visible = 'off';
                    
                %filter domain case 2
                case app.FrequencyDomainButton
                    
                    %turn frequency domain visuals on and set to none
                    app.FrequencyDomainFilterOptionsDropDown.Value = 'None';
                    app.FrequencyDomainFilterOptionsDropDown.Visible = 'on';
                    app.FrequencyDomainFilterOptionsDropDownLabel.Visible = 'on';
                    
                    %turn spatial domain visuals off
                    app.SpatialDomainFilterOptionsDropDown.Visible = 'off';
                    app.SpatialDomainFilterOptionsDropDownLabel.Visible = 'off';
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    
                    %turn morphological filter visuals off
                    app.MorphilogicalFilterOptionsDropDown.Visible = 'off';
                    app.MorphologicalFilterOptionsLabel.Visible = 'off';
                    app.ColorSpaceDropDown.Visible = 'off';
                    app.ColorSpaceDropDownLabel.Visible = 'off';
                    app.StructuringElementButtonGroup.Visible = 'off';
                    
                    %turn rgb option visuals off
                    app.BlueMinimumEditField.Visible = 'off';
                    app.BlueMinimumEditFieldLabel.Visible = 'off';
                    app.GreenMinimumEditField.Visible = 'off';
                    app.GreenMinimumEditFieldLabel.Visible = 'off';
                    app.RedMinimumEditField.Visible = 'off';
                    app.RedMinimumEditFieldLabel.Visible = 'off';
                    app.BlueMaximumEditField.Visible = 'off';
                    app.BlueMaximumEditFieldLabel.Visible = 'off';
                    app.GreenMaximumEditField.Visible = 'off';
                    app.GreenMaximumEditFieldLabel.Visible = 'off';
                    app.RedMaximumEditField.Visible = 'off';
                    app.RedMaximumEditFieldLabel.Visible = 'off';
                    
                    %Turn hsv option visuals off
                    app.HueMinimumEditFieldLabel.Visible = 'off';
                    app.HueMinimumEditField.Visible = 'off';
                    app.HueMaximumEditFieldLabel.Visible = 'off';
                    app.HueMaximumEditField.Visible = 'off';
                    app.SaturationMinimumEditFieldLabel.Visible = 'off';
                    app.SaturationMinimumEditField.Visible = 'off';
                    app.SaturationMaximumEditFieldLabel.Visible = 'off';
                    app.SaturationMaximumEditField.Visible = 'off';
                    app.ValueMaximumEditFieldLabel.Visible = 'off';
                    app.ValueMaximumEditField.Visible = 'off';
                    app.ValueMinimumEditFieldLabel.Visible = 'off';
                    app.ValueMinimumEditField.Visible = 'off';
                    
                    %turn structuring element size visual off
                    app.StructuringElementSizeButtonGroup.Visible = 'off';
                    
                %filter domain case 3
                case app.MorphologicalFiltersButton
                    
                    %turn morphological filter visuals on and set to none
                    app.MorphilogicalFilterOptionsDropDown.Value = 'None';
                    app.MorphilogicalFilterOptionsDropDown.Visible = 'on';
                    app.MorphologicalFilterOptionsLabel.Visible = 'on';
                    
                    %turn frequency domain visuals off
                    app.FrequencyDomainFilterOptionsDropDown.Visible = 'off';
                    app.FrequencyDomainFilterOptionsDropDownLabel.Visible = 'off';
                    app.FilterTypeDropDown.Visible = 'off';
                    app.FilterTypeDropDownLabel.Visible = 'off';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.FirstCutoffFrequencyEditField.Visible = 'off';
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.SecondCutoffFrequencyEditField.Visible = 'off';
                    app.FrequencyDomainFilterOptionsDropDown.Value = 'None';
                    
                    %turn spatial domain visuals off
                    app.BrightnessSlider.Visible = 'off';
                    app.ContrastSlider.Visible = 'off';
                    app.BrightnessSliderLabel.Visible = 'off';
                    app.ContrastSliderLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    app.SpatialDomainFilterOptionsDropDown.Visible = 'off';
                    app.SpatialDomainFilterOptionsDropDownLabel.Visible = 'off';
                    app.NeighborhoodSizeButtonGroup.Visible = 'off';
                    
                    %turn gamma visuals off
                    app.GammaLowEditField.Visible = 'off';
                    app.GammaLowEditFieldLabel.Visible = 'off';
                    app.GammaHighEditField.Visible = 'off';
                    app.GammaHighEditFieldLabel.Visible = 'off';
                    
            end%filter domain switch ends
            
        end

        % Value changed function: FrequencyDomainFilterOptionsDropDown
        function FrequencyDomainFilterOptionsDropDownValueChanged(app, event)
            app.filterOption = app.FrequencyDomainFilterOptionsDropDown.Value;
               
            %filter option switch starting point
            switch app.filterOption
                
                %filter option case 1
                case 'None'
                    
                    %Turn all frequency domain visuals off
                    app.FilterTypeDropDown.Visible = 'off';
                    app.FilterTypeDropDownLabel.Visible = 'off';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.FirstCutoffFrequencyEditField.Visible = 'off';
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.SecondCutoffFrequencyEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    
                    %Set filter type to none
                    app.FilterTypeDropDown.Value = 'None';
                    app.filterType = 'None';
                    
                    %turn gamma visuals on
                    app.GammaLowEditField.Visible = 'off';
                    app.GammaLowEditFieldLabel.Visible = 'off';
                    app.GammaHighEditField.Visible = 'off';
                    app.GammaHighEditFieldLabel.Visible = 'off';
                
                %filter option case 2
                case 'Low-Pass'
                    
                    %Turn needed frequency domain visuals on
                    app.FilterTypeDropDown.Visible = 'on';
                    app.FilterTypeDropDownLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditField.Visible = 'on';
                    
                    %Turn uneeded frequency domain visuals off
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.SecondCutoffFrequencyEditField.Visible = 'off';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    
                    %Set filter type to none
                    app.FilterTypeDropDown.Value = 'None';
                    app.filterType = 'None';
                    
                    %turn gamma visuals off
                    app.GammaLowEditField.Visible = 'off';
                    app.GammaLowEditFieldLabel.Visible = 'off';
                    app.GammaHighEditField.Visible = 'off';
                    app.GammaHighEditFieldLabel.Visible = 'off';
                    
                %filter option case 3
                case 'High-Pass'
                    
                    %Turn needed frequency domain visuals on
                    app.FilterTypeDropDown.Visible = 'on';
                    app.FilterTypeDropDownLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditField.Visible = 'on';
                    
                    %Turn uneeded frequency domain visuals off
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.SecondCutoffFrequencyEditField.Visible = 'off';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    
                    %Set filter type to none
                    app.FilterTypeDropDown.Value = 'None';
                    app.filterType = 'None';
                    
                    %turn gamma visuals off
                    app.GammaLowEditField.Visible = 'off';
                    app.GammaLowEditFieldLabel.Visible = 'off';
                    app.GammaHighEditField.Visible = 'off';
                    app.GammaHighEditFieldLabel.Visible = 'off';
                    
                %filter option case 4
                case 'Highboost'  % User selected a defined option
                    
                    %Turn needed frequency domain visuals on
                    app.BoostScalingCoefficientEditField.Visible = 'on';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'on';
                    app.FilterTypeDropDown.Visible = 'on';
                    app.FilterTypeDropDownLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditField.Visible = 'on';
                    
                    %Turn uneeded frequency domain visuals off
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.SecondCutoffFrequencyEditField.Visible = 'off';
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    
                    %Set filter type to none
                    app.FilterTypeDropDown.Value = 'None';
                    app.filterType = 'None';
                    
                    %turn gamma visuals off
                    app.GammaLowEditField.Visible = 'off';
                    app.GammaLowEditFieldLabel.Visible = 'off';
                    app.GammaHighEditField.Visible = 'off';
                    app.GammaHighEditFieldLabel.Visible = 'off';
                    
                %filter option case 5
                case 'Band-Pass'
                    
                    %Turn needed frequency domain visuals on
                    app.FilterTypeDropDown.Visible = 'on';
                    app.FilterTypeDropDownLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditField.Visible = 'on';
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.SecondCutoffFrequencyEditField.Visible = 'on';
                    
                    %Turn uneeded frequency domain visuals off
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    
                    %turn gamma visuals off
                    app.GammaLowEditField.Visible = 'off';
                    app.GammaLowEditFieldLabel.Visible = 'off';
                    app.GammaHighEditField.Visible = 'off';
                    app.GammaHighEditFieldLabel.Visible = 'off';
                
                %filter option case 6
                case 'Band-Stop'
                    
                    %Turn needed frequency domain visuals on
                    app.FilterTypeDropDown.Visible = 'on';
                    app.FilterTypeDropDownLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditField.Visible = 'on';
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.SecondCutoffFrequencyEditField.Visible = 'on';
                    
                    %Turn uneeded frequency domain visuals off
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    
                    %Set filter type to none
                    app.FilterTypeDropDown.Value = 'None';
                    app.filterType = 'None';
                    
                    %turn gamma visuals off
                    app.GammaLowEditField.Visible = 'off';
                    app.GammaLowEditFieldLabel.Visible = 'off';
                    app.GammaHighEditField.Visible = 'off';
                    app.GammaHighEditFieldLabel.Visible = 'off';
                    
                %filter option case 7
                case 'Homomorphic'
                    
                    %Turn needed frequency domain visuals on
                    app.FirstCutoffFrequencyEditFieldLabel.Visible = 'on';
                    app.FirstCutoffFrequencyEditField.Visible = 'on';
                    
                    %Turn uneeded frequency domain visuals off
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                    app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
                    app.SecondCutoffFrequencyEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditField.Visible = 'off';
                    app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
                    app.FilterTypeDropDown.Visible = 'off';
                    app.FilterTypeDropDownLabel.Visible = 'off';
                    
                    %Set filter type to none
                    app.FilterTypeDropDown.Value = 'None';
                    app.filterType = 'None';
                    
                    %turn gamma visuals on
                    app.GammaLowEditField.Visible = 'on';
                    app.GammaLowEditFieldLabel.Visible = 'on';
                    app.GammaHighEditField.Visible = 'on';
                    app.GammaHighEditFieldLabel.Visible = 'on';
            
            end%filter option switch ends
            
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            %set the filter type
            app.filterType = app.FilterTypeDropDown.Value;
            
            %This is a filter type switch starting point
            switch app.filterType
                
                %filter type case 1
                case 'Ideal'
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                  
                %filter type case 2
                case 'Gaussian'
                    app.ButterworthFilterOrderDropDown.Visible = 'off';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
                 
                %filter type case 3
                case 'Butterworth'
                    app.ButterworthFilterOrderDropDown.Visible = 'on';
                    app.ButterworthFilterOrderDropDownLabel.Visible = 'on';
            
            end%filter type switch ends
            
        end

        % Button pushed function: GeneratePreviewButton
        function GeneratePreviewButtonPushed(app, event)
            %Display for debugging
            disp(app.filterDomain);
            disp(app.filterOption);
            disp(app.filterType);
            
            %Loading Window
            d = uiprogressdlg(app.FinalProjectCadenSandersUIFigure,'Title','Generating Preview...',...
                'Indeterminate','on');
   
            %set reverse low to begin with (acts as a flag)
            reverse = 0;
            
            %This is the filter domain switch starting point
            %--------------------------------------------------------------------|
            switch app.filterDomain
                
                %filter domain case 1
                case app.SpatialDomainButton
                    
                    %This is a filter option starting point
                    %************************************************************|
                    switch app.filterOption
                        
                        %filter option case 1
                        case 'Brightness'
                            if size(app.imageData,3)==3
                                fIm=rgb2ntsc(app.imageData);
                            else
                                fIm=double(app.imageData)./255;
                            end%ifelse ends
                            fIm(:,:,1)=fIm(:,:,1)+app.brightness*(1-fIm(:,:,1));
                            if size(app.imageData,3)==3
                                fIm=ntsc2rgb(fIm);
                            end%if ends
                            
                        %filter option case 2
                        case 'Contrast'
                            fIm = imadjust(app.imageData,stretchlim(app.imageData),[],app.contrast);
                            
                        %filter option case 3
                        case 'Smoothing Low-Pass'
                            if app.NeighborhoodSizeButtonGroup.SelectedObject == app.x3Button
                                h = ones(3,3)/9;
                                fIm = imfilter(app.imageData,h);
                            elseif app.NeighborhoodSizeButtonGroup.SelectedObject == app.x5Button
                                h = ones(5,5)/25;
                                fIm = imfilter(app.imageData,h);
                            elseif app.NeighborhoodSizeButtonGroup.SelectedObject == app.x7Button
                                h = ones(7,7)/49;
                                fIm = imfilter(app.imageData,h);
                            elseif app.NeighborhoodSizeButtonGroup.SelectedObject == app.x9Button
                                h = ones(9,9)/81;
                                fIm = imfilter(app.imageData,h);
                            end%ifelse ends
                            
                        %filter option case 4
                        case 'Edge Detect High-Pass'
                            h = ones(3,3).*-1;
                            h(2,2) = 8;
                            fIm = imfilter(app.imageData,h);
                            
                        %filter option case 5
                        case 'Highboost'
                            h = [0 -1 0;-1 5 -1;0 -1 0];
                            h(2,2) = 5+(app.highboostScale-1);
                            fIm = imfilter(app.imageData,h);
                            
                        %filter option case 6
                        case 'Global Histogram-Equalization'
                            fIm = histeq(app.imageData);
                            
                        %filter option case 7
                        case 'Adaptive Histogram-Equalization'
                            [rows, columns, numberOfColorChannels] = size(app.imageData);
                            if numberOfColorChannels > 1
                                LAB = rgb2lab(app.imageData);
                                L = LAB(:,:,1)/100;
                                L = adapthisteq(L,'NumTiles',[8 8],'ClipLimit',0.005);
                                LAB(:,:,1) = L*100;
                                fIm = lab2rgb(LAB);
                            else
                                fIm = adapthisteq(app.imageData,'clipLimit',0.02,'Distribution','rayleigh');
                            end%if else ends
                            
                        %filter option case 8
                        otherwise
                            f = msgbox('Please choose an option','Error','error');
                            return;
                            
                    %************************************************************|
                    end%filter option switch ends
                    
                %filter domain case 2
                case app.FrequencyDomainButton
                    
                    %Run this for every frequency domain filtering
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    [rows, columns, numberOfColorChannels] = size(app.imageData);
                    if numberOfColorChannels > 1
                        fIm = rgb2ycbcr(app.imageData);
                        h = fIm(:,:,1);
                        h = double(h);
                    else
                        h=double(app.imageData);
                    end%ifelse ends
                    
                    h2=fft2_centered(h);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    %This is a filter option switch starting point
                    %************************************************************|
                    switch app.filterOption
                        
                        %filter option case 1
                        case 'Low-Pass'
                            
                            %This is a filter type switch starting point
                            %+++++++++++++++++++++++++++++++++++++++++++++++++++|
                            switch app.filterType
                                
                                %filter type case 1
                                case 'Ideal'
                                    h3=ideal_lowpass_centered_freq(h2,app.thresh);
                                    
                                    %filter type case 2
                                case 'Gaussian'
                                    h3=gaussian_low_center_f(h2,app.thresh);
                                    
                                    %filter type case 3
                                case 'Butterworth'
                                    h3=butterworth_low_center_f(h2,app.thresh,app.order);
                                    
                                    %filter type case 4
                                otherwise
                                    f = msgbox('Please choose an option','Error','error');
                                    return;
                                    
                            %+++++++++++++++++++++++++++++++++++++++++++++++++++|
                            end% filter option switch ends
                            
                        %filter option case 2
                        case 'High-Pass'
                            
                            %This is a filter type switch starting point
                            %+++++++++++++++++++++++++++++++++++++++++++++++++++|
                            switch app.filterType
                                
                                %filter type case 1
                                case 'Ideal'
                                    h3 = ideal_highpass_centered_freq(h2,app.thresh);
                                    
                                    %filter type case 2
                                case 'Gaussian'
                                    h3 = gaussian_high_center_f(h2,app.thresh);
                                    
                                    %filter type case 3
                                case 'Butterworth'
                                    h3 = butterworth_high_center_f(h2,app.thresh,app.order);
                                    
                                    %filter type case 4
                                otherwise
                                    f = msgbox('Please choose an option','Error','error');
                                    return;
                                    
                            %+++++++++++++++++++++++++++++++++++++++++++++++++++|
                            end%filter type switch ends
                            
                        %filter option case 3
                        case 'Highboost'
                            h = [0 -1 0;-1 5 -1;0 -1 0];
                            h(2,2) = 5+(app.highboostScale-1);
                            fIm = imfilter(app.imageData,h);
                            
                        %filter option case 4
                        case 'Band-Pass'
                            
                            %This is a filter type switch starting point
                            %+++++++++++++++++++++++++++++++++++++++++++++++++++|
                            switch app.filterType
                                
                                %filter type case 1
                                case 'Ideal'
                                    h3 = ideal_bandpass_centered_freq(h2,app.thresh,app.thresh2);
                                    
                                %filter type case 2
                                case 'Gaussian'
                                    fIm = ffilter(app.imageData,'Bandpass','Gaussian',app.thresh,app.thresh2);
                                    reverse=1;
                                    
                                %filter type case 3
                                case 'Butterworth'
                                    disp(app.order);
                                    fIm = ffilter(app.imageData,'Bandpass','Butterworth',app.thresh,app.thresh2,uint8(app.order));
                                    reverse=1;
                                    
                                %filter type case 4
                                otherwise
                                    f = msgbox('Please choose an option','Error','error');
                                    return;
                                    
                            %+++++++++++++++++++++++++++++++++++++++++++++++++++|
                            end%filter type switch ends
                            
                        %filter option case 5
                        case 'Band-Stop'
                            
                            %This is a filter type switch starting point
                            %+++++++++++++++++++++++++++++++++++++++++++++++++++|
                            switch app.filterType
                                
                                %filter type case 1
                                case 'Ideal'
                                    h3 = ideal_bandstop_centered_freq(h2,app.thresh,app.thresh2);
                                
                                %filter type case 2
                                case 'Gaussian'
                                    fIm = ffilter(app.imageData,'Bandstop','Gaussian',app.thresh,app.thresh2);
                                    reverse=1;
                                    
                                %filter type case 3
                                case 'Butterworth'
                                    fIm = ffilter(app.imageData,'Bandstop','Butterworth',app.thresh,app.thresh2,uint8(app.order));
                                    reverse=1;
                                    
                                %fitler type case 4
                                otherwise
                                    f = msgbox('Please choose an option','Error','error');
                                    return;
                                    
                            %+++++++++++++++++++++++++++++++++++++++++++++++++++|
                            end%filter type switch ends
                            
                        %filter option case 6
                        case 'Homomorphic'
                            h3 = homomorphic_gamma(h2,app.thresh,app.gammaLow,app.gammaHigh);
                            
                        %filter option case 7
                        otherwise
                            f = msgbox('Please choose an option','Error','error');
                            return;
                    
                    %************************************************************|
                    end%filter option switch ends
                    
                    %Run this for every frequency domain filtering
                    %Unless reverse is specifically set high
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if reverse==0
                        %inverse 2D fft
                        if numberOfColorChannels > 1
                            fIm(:,:,1) = abs(real(ifft2(h3)));
                            fIm = ycbcr2rgb(fIm);
                        else
                            fIm = abs(real(ifft2(h3)));
                        end%ifelse ends
                        fIm=uint8(fIm);
                    end%if ends
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                %filter domain case 3
                case app.MorphologicalFiltersButton
                    
                    %This is a filter Option switch starting point
                    %************************************************************|
                    switch app.MorphilogicalFilterOptionsDropDown.Value
                        
                        %filter option case 1
                        case 'Generate Mask'
                            
                            %This is the color space switch starting point
                            %+++++++++++++++++++++++++++++++++++++++++++++++++++|
                            switch app.colorSpace
                                
                                %color space case 1
                                case 'Red Green Blue'
                                    % Convert RGB image to chosen color space
                                    I = app.imageData;
                                    
                                    % Define thresholds for channel 1 based on histogram settings
                                    channel1Min = app.RedMinimumEditField.Value;
                                    channel1Max = app.RedMaximumEditField.Value;
                                    
                                    % Define thresholds for channel 2 based on histogram settings
                                    channel2Min = app.GreenMinimumEditField.Value;
                                    channel2Max = app.GreenMaximumEditField.Value;
                                    
                                    % Define thresholds for channel 3 based on histogram settings
                                    channel3Min = app.BlueMinimumEditField.Value;
                                    channel3Max = app.BlueMaximumEditField.Value;
                                    
                                    % Create mask based on chosen histogram thresholds
                                    sliderBW = (I(:,:,1) >= channel1Min ) & (I(:,:,1) <= channel1Max) & ...
                                        (I(:,:,2) >= channel2Min ) & (I(:,:,2) <= channel2Max) & ...
                                        (I(:,:,3) >= channel3Min ) & (I(:,:,3) <= channel3Max);
                                    BW = sliderBW;
                                    
                                    % Initialize output masked image based on input image.
                                    h = app.imageData;
                                    h(:,:) = 255;
                                    
                                    % Set background pixels where BW is false to zero.
                                    h(repmat(~BW,[1 1 3])) = 0;
                                    
                                    [rows, columns, numberOfColorChannels] = size(app.imageData);
                                    if numberOfColorChannels > 1
                                        fIm = rgb2ycbcr(h);
                                        h = fIm(:,:,1);
                                    end%if ends
                                    
                                    fIm = roicolor(h,200,256);
                                    
                                %color space case 2
                                case 'Hue Saturation Value'
                                    % Convert RGB image to chosen color space
                                    I = rgb2hsv(app.imageData);
                                    
                                    % Define thresholds for channel 1 based on histogram settings
                                    channel1Min = app.HueMinimumEditField.Value;
                                    channel1Max = app.HueMaximumEditField.Value;
                                    
                                    % Define thresholds for channel 2 based on histogram settings
                                    channel2Min = app.SaturationMinimumEditField.Value;
                                    channel2Max = app.SaturationMaximumEditField.Value;
                                    
                                    % Define thresholds for channel 3 based on histogram settings
                                    channel3Min = app.ValueMinimumEditField.Value;
                                    channel3Max = app.ValueMaximumEditField.Value;
                                    
                                    % Create mask based on chosen histogram thresholds
                                    sliderBW = ( (I(:,:,1) >= channel1Min) | (I(:,:,1) <= channel1Max) ) & ...
                                        (I(:,:,2) >= channel2Min ) & (I(:,:,2) <= channel2Max) & ...
                                        (I(:,:,3) >= channel3Min ) & (I(:,:,3) <= channel3Max);
                                    BW = sliderBW;
                                    
                                    % Initialize output masked image based on input image.
                                    h = app.imageData;
                                    h(:,:) = 255;
                                    
                                    % Set background pixels where BW is false to zero.
                                    h(repmat(~BW,[1 1 3])) = 0;
                                    
                                    [rows, columns, numberOfColorChannels] = size(app.imageData);
                                    if numberOfColorChannels > 1
                                        fIm = rgb2ycbcr(h);
                                        h = fIm(:,:,1);
                                    end%if ends
                                    
                                    fIm = roicolor(h,200,256);
                                    
                            %+++++++++++++++++++++++++++++++++++++++++++++++++++|
                            end%color space switch ends
                          
                        %filter option case 5
                        case 'Erosion'
                            
                            %Thisis a structuring element switch starting point
                            switch app.structEl
                                
                                %structuring element case 1
                                case app.SquareButton
                                    SE = strel('square',app.squareStructSize);
                                
                                %structuring element case 1
                                case app.RectangleButton
                                    SE = strel('rectangle',app.rectangleStructSize);
                                    
                                %structuring element case 1
                                case app.CircleDiskButton
                                    SE = strel('disk',app.circleStructSize);
                                    
                            end%structuring element switch ends
                            
                            %apply the erosion
                            fIm = imerode(app.imageData,SE);
                            
                        %filter option case 6
                        case 'Dilation'
                            
                            %This is a structuring element switch starting point
                            switch app.structEl
                                
                                %structuring element case 1
                                case app.SquareButton
                                    s = floor(app.squareStructSize/3);
                                    SE = strel('square',s);
                                
                                %structuring element case 2
                                case app.RectangleButton
                                    s = [floor(app.rectangleStructSize(1)/2) floor(app.rectangleStructSize(2)/2)];
                                    SE = strel('rectangle',s);
                                    
                                %structuring element case 3
                                case app.CircleDiskButton
                                    s = floor(app.circleStructSize/4);
                                    SE = strel('disk',s);
                                    
                            end%structuring element switch ends
                            
                            %apply the dilation
                            fIm = imdilate(app.imageData,SE);
                            
                        %filter option case 7
                        case 'Opening'
                            
                            %This is a structuring element switch starting point
                            switch app.structEl
                                
                                %structuring element case 1
                                case app.SquareButton
                                    s = floor(app.squareStructSize*2);
                                    SE = strel('square',s);
                                
                                %structuring element case 1
                                case app.RectangleButton
                                    s = [floor(app.rectangleStructSize(1)*2) floor(app.rectangleStructSize(2)*2)];
                                    SE = strel('rectangle',s);
                                    
                                %structuring element case 1
                                case app.CircleDiskButton
                                    s = floor(app.circleStructSize*2);
                                    SE = strel('disk',s);
                                    
                            end%structuring element switch ends
                            
                            %apply the dilation
                            fIm = imopen(app.imageData,SE);
                            
                        %filter option case 8
                        case 'Closing'
                            
                            %This is a structuring element switch starting point
                            switch app.structEl
                                %structuring element case 1
                                case app.SquareButton
                                    s = floor(app.squareStructSize/2.5);
                                    SE = strel('square',s);
                                
                                %structuring element case 1
                                case app.RectangleButton
                                    s = [floor(app.rectangleStructSize(1)/2) floor(app.rectangleStructSize(2)/2)];
                                    SE = strel('rectangle',s);
                                    
                                %structuring element case 1
                                case app.CircleDiskButton
                                    s = floor(app.circleStructSize/4);
                                    SE = strel('disk',s);
                                    
                            end%structuring element switch ends
                            
                            %apply the dilation
                            fIm = imclose(app.imageData,SE);
                            
                        %filter option case 9
                        case 'Boundary (Beta)'
                            fIm = boundarymask(app.imageData);
                            
                        case 'Object Recognition';
                            
                            %This is the color space switch starting point
                            switch app.colorSpace
                        
                                %color space case 1
                                case 'Red Green Blue'
                            
                                    % Convert RGB image to chosen color space
                                    I = app.imageData;
                                    
                                    % Define thresholds for channel 1 based on histogram settings
                                    channel1Min = app.RedMinimumEditField.Value;
                                    channel1Max = app.RedMaximumEditField.Value;
                                    
                                    % Define thresholds for channel 2 based on histogram settings
                                    channel2Min = app.GreenMinimumEditField.Value;
                                    channel2Max = app.GreenMaximumEditField.Value;
                                    
                                    % Define thresholds for channel 3 based on histogram settings
                                    channel3Min = app.BlueMinimumEditField.Value;
                                    channel3Max = app.BlueMaximumEditField.Value;
                                    
                                    % Create mask based on chosen histogram thresholds
                                    sliderBW = (I(:,:,1) >= channel1Min ) & (I(:,:,1) <= channel1Max) & ...
                                        (I(:,:,2) >= channel2Min ) & (I(:,:,2) <= channel2Max) & ...
                                        (I(:,:,3) >= channel3Min ) & (I(:,:,3) <= channel3Max);
                                    BW = sliderBW;
                                    
                                    % Initialize output masked image based on input image.
                                    h = app.imageData;
                                    h(:,:) = 255;
                                    
                                    % Set background pixels where BW is false to zero.
                                    h(repmat(~BW,[1 1 3])) = 0;
                                    
                                    [rows, columns, numberOfColorChannels] = size(app.imageData);
                                    if numberOfColorChannels > 1
                                        mask = rgb2ycbcr(h);
                                        h = mask(:,:,1);
                                    end%if ends
                                    
                                    mask = roicolor(h,200,256);
                                
                                %color space case 2
                                case 'Hue Saturation Value'
                                    
                                    % Convert RGB image to chosen color space
                                    I = rgb2hsv(app.imageData);
                                    
                                    % Define thresholds for channel 1 based on histogram settings
                                    channel1Min = app.HueMinimumEditField.Value;
                                    channel1Max = app.HueMaximumEditField.Value;
                                    
                                    % Define thresholds for channel 2 based on histogram settings
                                    channel2Min = app.SaturationMinimumEditField.Value;
                                    channel2Max = app.SaturationMaximumEditField.Value;
                                    
                                    % Define thresholds for channel 3 based on histogram settings
                                    channel3Min = app.ValueMinimumEditField.Value;
                                    channel3Max = app.ValueMaximumEditField.Value;
                                    
                                    % Create mask based on chosen histogram thresholds
                                    sliderBW = ( (I(:,:,1) >= channel1Min) | (I(:,:,1) <= channel1Max) ) & ...
                                        (I(:,:,2) >= channel2Min ) & (I(:,:,2) <= channel2Max) & ...
                                        (I(:,:,3) >= channel3Min ) & (I(:,:,3) <= channel3Max);
                                    BW = sliderBW;
                                    
                                    % Initialize output masked image based on input image.
                                    h = app.imageData;
                                    h(:,:) = 255;
                                    
                                    % Set background pixels where BW is false to zero.
                                    h(repmat(~BW,[1 1 3])) = 0;
                                    
                                    [rows, columns, numberOfColorChannels] = size(app.imageData);
                                    if numberOfColorChannels > 1
                                        mask = rgb2ycbcr(h);
                                        h = mask(:,:,1);
                                    end%if ends
                                    
                                    mask = roicolor(h,200,256);
                                
                            end%color space switch ends
                            
                            redChannel = app.imageData(:, :, 1);
                            greenChannel = app.imageData(:, :, 2);
                            blueChannel = app.imageData(:, :, 3);
                            
                            % Specify the color we want to make this area.
                            desiredColor = [200, 200, 200]; % Purple
                            
                            redChannel(~mask) = desiredColor(1);
                            greenChannel(~mask) = desiredColor(2);
                            blueChannel(~mask) = desiredColor(3);
                            
                            % Recombine separate color channels into a single, true color RGB image.
                            fIm = cat(3, redChannel, greenChannel, blueChannel);
                            
                        %filter option case 12
                        otherwise
                            f = msgbox('Please choose an option','Error','error');
                            return;
                        
                    %************************************************************|
                    end%filter option switch ends
                            
                %filter domain case 4
                otherwise
                    f = msgbox('Please choose an option','Error','error');
                    return;
                         
            %--------------------------------------------------------------------|
            end%filter domain switch ends


                    %This is where the preview image is displayed
                    cla(app.UIAxes_2, 'reset')
                    imshow(fIm, 'Parent', app.UIAxes_2);
                    app.UIAxes_2.XLabel.Visible = 'off';
                    app.UIAxes_2.YLabel.Visible = 'off';
                    app.UIAxes_2.Title.Visible = 'off';
                    app.UIAxes_2.XLim = [0 inf];
                    app.UIAxes_2.YLim = [0 inf];
                    app.preview = fIm;
                    % close the dialog box
                    close(d);
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            %set the brightness property
            app.brightness = app.BrightnessSlider.Value;
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            %set the contrast property
            app.contrast = app.ContrastSlider.Value;
        end

        % Value changed function: BoostScalingCoefficientEditField
        function BoostScalingCoefficientEditFieldValueChanged(app, event)
            %set the highboost property
            app.highboostScale = app.BoostScalingCoefficientEditField.Value;
        end

        % Value changed function: ButterworthFilterOrderDropDown
        function ButterworthFilterOrderDropDownValueChanged(app, event)
            %set the order property
            app.order = app.ButterworthFilterOrderDropDown.Value;
        end

        % Value changed function: FirstCutoffFrequencyEditField
        function FirstCutoffFrequencyEditFieldValueChanged(app, event)
            %set the first cutoff property
            app.thresh = app.FirstCutoffFrequencyEditField.Value;
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            %set the image data as the preview image    
            app.imageData = app.preview;
                
            %clear all axes and prepare settings
            cla(app.UIAxes,'reset')
            cla(app.UIAxes_2,'reset')
            app.UIAxes_2.XLabel.Visible = 'off';
            app.UIAxes_2.YLabel.Visible = 'off';
            app.UIAxes_2.Title.Visible = 'off';
            app.UIAxes_2.XLim = [0 inf];
            app.UIAxes_2.YLim = [0 inf];
            app.UIAxes.XLabel.Visible = 'off';
            app.UIAxes.YLabel.Visible = 'off';
            app.UIAxes.Title.Visible = 'off';
            app.UIAxes.XLim = [0 inf];
            app.UIAxes.YLim = [0 inf];
            
            %display the new image data
            imshow(app.imageData, 'Parent', app.UIAxes);
        end

        % Value changed function: SecondCutoffFrequencyEditField
        function SecondCutoffFrequencyEditFieldValueChanged(app, event)
            %set the second cutoff property
            app.thresh2 = app.SecondCutoffFrequencyEditField.Value;
        end

        % Value changed function: MorphilogicalFilterOptionsDropDown
        function MorphilogicalFilterOptionsDropDownValueChanged(app, event)
                
            %set the filter option and color space
            app.filterOption = app.MorphilogicalFilterOptionsDropDown.Value;
            app.colorSpace = app.ColorSpaceDropDown.Value;
                
            %This is the filter option switch starting point
            switch app.filterOption
                
                %filter option case 1
                case 'None'
                    
                    %turn the color space visuals off
                    app.ColorSpaceDropDown.Visible = 'off';
                    app.ColorSpaceDropDownLabel.Visible = 'off';
                    
                    %turn structuring element visual off
                    app.StructuringElementButtonGroup.Visible = 'off';
                    
                    %Turn the rgb value visuals off
                    app.BlueMinimumEditField.Visible = 'off';
                    app.BlueMinimumEditFieldLabel.Visible = 'off';
                    app.GreenMinimumEditField.Visible = 'off';
                    app.GreenMinimumEditFieldLabel.Visible = 'off';
                    app.RedMinimumEditField.Visible = 'off';
                    app.RedMinimumEditFieldLabel.Visible = 'off';
                    app.BlueMaximumEditField.Visible = 'off';
                    app.BlueMaximumEditFieldLabel.Visible = 'off';
                    app.GreenMaximumEditField.Visible = 'off';
                    app.GreenMaximumEditFieldLabel.Visible = 'off';
                    app.RedMaximumEditField.Visible = 'off';
                    app.RedMaximumEditFieldLabel.Visible = 'off';
                    
                    %Turn the hsv value visuals off
                    app.HueMinimumEditFieldLabel.Visible = 'off';
                    app.HueMinimumEditField.Visible = 'off';
                    app.HueMaximumEditFieldLabel.Visible = 'off';
                    app.HueMaximumEditField.Visible = 'off';
                    app.SaturationMinimumEditFieldLabel.Visible = 'off';
                    app.SaturationMinimumEditField.Visible = 'off';
                    app.SaturationMaximumEditFieldLabel.Visible = 'off';
                    app.SaturationMaximumEditField.Visible = 'off';
                    app.ValueMaximumEditFieldLabel.Visible = 'off';
                    app.ValueMaximumEditField.Visible = 'off';
                    app.ValueMinimumEditFieldLabel.Visible = 'off';
                    app.ValueMinimumEditField.Visible = 'off';
                    
                    %turn structuring element size visual off
                    app.StructuringElementSizeButtonGroup.Visible = 'off';
                
                %filter option case 2
                case 'Generate Mask'
                    
                    %turn the color space visuals on
                    app.ColorSpaceDropDown.Visible = 'on';
                    app.ColorSpaceDropDownLabel.Visible = 'on';
                    
                    %turn structuring element visual off
                    app.StructuringElementButtonGroup.Visible = 'off';
                    
                    %This is the color space switch starting point
                    switch app.colorSpace
                        
                        %color space case 1
                        case 'Red Green Blue'
                            
                            %Turn the rgb value visuals on
                            app.BlueMinimumEditField.Visible = 'on';
                            app.BlueMinimumEditFieldLabel.Visible = 'on';
                            app.GreenMinimumEditField.Visible = 'on';
                            app.GreenMinimumEditFieldLabel.Visible = 'on';
                            app.RedMinimumEditField.Visible = 'on';
                            app.RedMinimumEditFieldLabel.Visible = 'on';
                            app.BlueMaximumEditField.Visible = 'on';
                            app.BlueMaximumEditFieldLabel.Visible = 'on';
                            app.GreenMaximumEditField.Visible = 'on';
                            app.GreenMaximumEditFieldLabel.Visible = 'on';
                            app.RedMaximumEditField.Visible = 'on';
                            app.RedMaximumEditFieldLabel.Visible = 'on';
                            
                            %Turn the hsv value visuals off
                            app.HueMinimumEditFieldLabel.Visible = 'off';
                            app.HueMinimumEditField.Visible = 'off';
                            app.HueMaximumEditFieldLabel.Visible = 'off';
                            app.HueMaximumEditField.Visible = 'off';
                            app.SaturationMinimumEditFieldLabel.Visible = 'off';
                            app.SaturationMinimumEditField.Visible = 'off';
                            app.SaturationMaximumEditFieldLabel.Visible = 'off';
                            app.SaturationMaximumEditField.Visible = 'off';
                            app.ValueMaximumEditFieldLabel.Visible = 'off';
                            app.ValueMaximumEditField.Visible = 'off';
                            app.ValueMinimumEditFieldLabel.Visible = 'off';
                            app.ValueMinimumEditField.Visible = 'off';
                            
                        %color space case 2
                        case 'Hue Saturation Value'
                            
                            %Turn the rgb value visuals off
                            app.BlueMinimumEditField.Visible = 'off';
                            app.BlueMinimumEditFieldLabel.Visible = 'off';
                            app.GreenMinimumEditField.Visible = 'off';
                            app.GreenMinimumEditFieldLabel.Visible = 'off';
                            app.RedMinimumEditField.Visible = 'off';
                            app.RedMinimumEditFieldLabel.Visible = 'off';
                            app.BlueMaximumEditField.Visible = 'off';
                            app.BlueMaximumEditFieldLabel.Visible = 'off';
                            app.GreenMaximumEditField.Visible = 'off';
                            app.GreenMaximumEditFieldLabel.Visible = 'off';
                            app.RedMaximumEditField.Visible = 'off';
                            app.RedMaximumEditFieldLabel.Visible = 'off';
                            
                            %Turn the hsv value visuals on
                            app.HueMinimumEditFieldLabel.Visible = 'on';
                            app.HueMinimumEditField.Visible = 'on';
                            app.HueMaximumEditFieldLabel.Visible = 'on';
                            app.HueMaximumEditField.Visible = 'on';
                            app.SaturationMinimumEditFieldLabel.Visible = 'on';
                            app.SaturationMinimumEditField.Visible = 'on';
                            app.SaturationMaximumEditFieldLabel.Visible = 'on';
                            app.SaturationMaximumEditField.Visible = 'on';
                            app.ValueMaximumEditFieldLabel.Visible = 'on';
                            app.ValueMaximumEditField.Visible = 'on';
                            app.ValueMinimumEditFieldLabel.Visible = 'on';
                            app.ValueMinimumEditField.Visible = 'on';
                            
                    end%color space switch ends
                    
                    %turn structuring element size visual off
                    app.StructuringElementSizeButtonGroup.Visible = 'off';
                    
                %filter option case 3
                case 'Erosion'
                    
                    %turn the color space visuals off
                    app.ColorSpaceDropDown.Visible = 'off';
                    app.ColorSpaceDropDownLabel.Visible = 'off';
                    
                    %turn structuring element visual on
                    app.StructuringElementButtonGroup.Visible = 'on';
                    
                    %Turn the rgb value visuals off
                    app.BlueMinimumEditField.Visible = 'off';
                    app.BlueMinimumEditFieldLabel.Visible = 'off';
                    app.GreenMinimumEditField.Visible = 'off';
                    app.GreenMinimumEditFieldLabel.Visible = 'off';
                    app.RedMinimumEditField.Visible = 'off';
                    app.RedMinimumEditFieldLabel.Visible = 'off';
                    app.BlueMaximumEditField.Visible = 'off';
                    app.BlueMaximumEditFieldLabel.Visible = 'off';
                    app.GreenMaximumEditField.Visible = 'off';
                    app.GreenMaximumEditFieldLabel.Visible = 'off';
                    app.RedMaximumEditField.Visible = 'off';
                    app.RedMaximumEditFieldLabel.Visible = 'off';
                    
                    %Turn the hsv value visuals off
                    app.HueMinimumEditFieldLabel.Visible = 'off';
                    app.HueMinimumEditField.Visible = 'off';
                    app.HueMaximumEditFieldLabel.Visible = 'off';
                    app.HueMaximumEditField.Visible = 'off';
                    app.SaturationMinimumEditFieldLabel.Visible = 'off';
                    app.SaturationMinimumEditField.Visible = 'off';
                    app.SaturationMaximumEditFieldLabel.Visible = 'off';
                    app.SaturationMaximumEditField.Visible = 'off';
                    app.ValueMaximumEditFieldLabel.Visible = 'off';
                    app.ValueMaximumEditField.Visible = 'off';
                    app.ValueMinimumEditFieldLabel.Visible = 'off';
                    app.ValueMinimumEditField.Visible = 'off';
                    
                    %turn structuring element size visual on
                    app.StructuringElementSizeButtonGroup.Visible = 'on';
                
                %filter option case 4
                case 'Dilation'
                    
                    %turn the color space visuals off
                    app.ColorSpaceDropDown.Visible = 'off';
                    app.ColorSpaceDropDownLabel.Visible = 'off';
                    
                    %turn structuring element visual on
                    app.StructuringElementButtonGroup.Visible = 'on';
                    
                    %Turn the rgb value visuals off
                    app.BlueMinimumEditField.Visible = 'off';
                    app.BlueMinimumEditFieldLabel.Visible = 'off';
                    app.GreenMinimumEditField.Visible = 'off';
                    app.GreenMinimumEditFieldLabel.Visible = 'off';
                    app.RedMinimumEditField.Visible = 'off';
                    app.RedMinimumEditFieldLabel.Visible = 'off';
                    app.BlueMaximumEditField.Visible = 'off';
                    app.BlueMaximumEditFieldLabel.Visible = 'off';
                    app.GreenMaximumEditField.Visible = 'off';
                    app.GreenMaximumEditFieldLabel.Visible = 'off';
                    app.RedMaximumEditField.Visible = 'off';
                    app.RedMaximumEditFieldLabel.Visible = 'off';
                    
                    %Turn the hsv value visuals off
                    app.HueMinimumEditFieldLabel.Visible = 'off';
                    app.HueMinimumEditField.Visible = 'off';
                    app.HueMaximumEditFieldLabel.Visible = 'off';
                    app.HueMaximumEditField.Visible = 'off';
                    app.SaturationMinimumEditFieldLabel.Visible = 'off';
                    app.SaturationMinimumEditField.Visible = 'off';
                    app.SaturationMaximumEditFieldLabel.Visible = 'off';
                    app.SaturationMaximumEditField.Visible = 'off';
                    app.ValueMaximumEditFieldLabel.Visible = 'off';
                    app.ValueMaximumEditField.Visible = 'off';
                    app.ValueMinimumEditFieldLabel.Visible = 'off';
                    app.ValueMinimumEditField.Visible = 'off';
                    
                    %turn structuring element size visual on
                    app.StructuringElementSizeButtonGroup.Visible = 'on';
                
                %filter option case 5
                case 'Opening'
                    
                    %turn the color space visuals off
                    app.ColorSpaceDropDown.Visible = 'off';
                    app.ColorSpaceDropDownLabel.Visible = 'off';
                    
                    %turn structuring element visual on
                    app.StructuringElementButtonGroup.Visible = 'on';
                    
                    %Turn the rgb value visuals off
                    app.BlueMinimumEditField.Visible = 'off';
                    app.BlueMinimumEditFieldLabel.Visible = 'off';
                    app.GreenMinimumEditField.Visible = 'off';
                    app.GreenMinimumEditFieldLabel.Visible = 'off';
                    app.RedMinimumEditField.Visible = 'off';
                    app.RedMinimumEditFieldLabel.Visible = 'off';
                    app.BlueMaximumEditField.Visible = 'off';
                    app.BlueMaximumEditFieldLabel.Visible = 'off';
                    app.GreenMaximumEditField.Visible = 'off';
                    app.GreenMaximumEditFieldLabel.Visible = 'off';
                    app.RedMaximumEditField.Visible = 'off';
                    app.RedMaximumEditFieldLabel.Visible = 'off';
                    
                    %Turn the hsv value selectors off
                    app.HueMinimumEditFieldLabel.Visible = 'off';
                    app.HueMinimumEditField.Visible = 'off';
                    app.HueMaximumEditFieldLabel.Visible = 'off';
                    app.HueMaximumEditField.Visible = 'off';
                    app.SaturationMinimumEditFieldLabel.Visible = 'off';
                    app.SaturationMinimumEditField.Visible = 'off';
                    app.SaturationMaximumEditFieldLabel.Visible = 'off';
                    app.SaturationMaximumEditField.Visible = 'off';
                    app.ValueMaximumEditFieldLabel.Visible = 'off';
                    app.ValueMaximumEditField.Visible = 'off';
                    app.ValueMinimumEditFieldLabel.Visible = 'off';
                    app.ValueMinimumEditField.Visible = 'off';
                    
                    %turn structuring element size visual on
                    app.StructuringElementSizeButtonGroup.Visible = 'on';
                
                %filter option case 6
                case 'Closing'
                    
                    %turn the color space visuals off
                    app.ColorSpaceDropDown.Visible = 'off';
                    app.ColorSpaceDropDownLabel.Visible = 'off';
                    
                    %turn structuring element visual on
                    app.StructuringElementButtonGroup.Visible = 'on';
                    
                    %Turn the rgb value visuals off
                    app.BlueMinimumEditField.Visible = 'off';
                    app.BlueMinimumEditFieldLabel.Visible = 'off';
                    app.GreenMinimumEditField.Visible = 'off';
                    app.GreenMinimumEditFieldLabel.Visible = 'off';
                    app.RedMinimumEditField.Visible = 'off';
                    app.RedMinimumEditFieldLabel.Visible = 'off';
                    app.BlueMaximumEditField.Visible = 'off';
                    app.BlueMaximumEditFieldLabel.Visible = 'off';
                    app.GreenMaximumEditField.Visible = 'off';
                    app.GreenMaximumEditFieldLabel.Visible = 'off';
                    app.RedMaximumEditField.Visible = 'off';
                    app.RedMaximumEditFieldLabel.Visible = 'off';
                    
                    %Turn the hsv value selectors off
                    app.HueMinimumEditFieldLabel.Visible = 'off';
                    app.HueMinimumEditField.Visible = 'off';
                    app.HueMaximumEditFieldLabel.Visible = 'off';
                    app.HueMaximumEditField.Visible = 'off';
                    app.SaturationMinimumEditFieldLabel.Visible = 'off';
                    app.SaturationMinimumEditField.Visible = 'off';
                    app.SaturationMaximumEditFieldLabel.Visible = 'off';
                    app.SaturationMaximumEditField.Visible = 'off';
                    app.ValueMaximumEditFieldLabel.Visible = 'off';
                    app.ValueMaximumEditField.Visible = 'off';
                    app.ValueMinimumEditFieldLabel.Visible = 'off';
                    app.ValueMinimumEditField.Visible = 'off';
                    
                    %turn structuring element size visual on
                    app.StructuringElementSizeButtonGroup.Visible = 'on';
                
                %filter option case 7
                case 'Boundary (Beta)'
                    
                    %turn the color space visuals off
                    app.ColorSpaceDropDown.Visible = 'off';
                    app.ColorSpaceDropDownLabel.Visible = 'off';
                    
                    %turn structuring element visual on
                    app.StructuringElementButtonGroup.Visible = 'on';
                    
                    %Turn the rgb value visuals off
                    app.BlueMinimumEditField.Visible = 'off';
                    app.BlueMinimumEditFieldLabel.Visible = 'off';
                    app.GreenMinimumEditField.Visible = 'off';
                    app.GreenMinimumEditFieldLabel.Visible = 'off';
                    app.RedMinimumEditField.Visible = 'off';
                    app.RedMinimumEditFieldLabel.Visible = 'off';
                    app.BlueMaximumEditField.Visible = 'off';
                    app.BlueMaximumEditFieldLabel.Visible = 'off';
                    app.GreenMaximumEditField.Visible = 'off';
                    app.GreenMaximumEditFieldLabel.Visible = 'off';
                    app.RedMaximumEditField.Visible = 'off';
                    app.RedMaximumEditFieldLabel.Visible = 'off';
                    
                    %Turn the hsv value selectors off
                    app.HueMinimumEditFieldLabel.Visible = 'off';
                    app.HueMinimumEditField.Visible = 'off';
                    app.HueMaximumEditFieldLabel.Visible = 'off';
                    app.HueMaximumEditField.Visible = 'off';
                    app.SaturationMinimumEditFieldLabel.Visible = 'off';
                    app.SaturationMinimumEditField.Visible = 'off';
                    app.SaturationMaximumEditFieldLabel.Visible = 'off';
                    app.SaturationMaximumEditField.Visible = 'off';
                    app.ValueMaximumEditFieldLabel.Visible = 'off';
                    app.ValueMaximumEditField.Visible = 'off';
                    app.ValueMinimumEditFieldLabel.Visible = 'off';
                    app.ValueMinimumEditField.Visible = 'off';
                    
                    %turn structuring element size visual on
                    app.StructuringElementSizeButtonGroup.Visible = 'off';
                    app.StructuringElementButtonGroup.Visible = 'off';
                    
                %filter option case 7
                case 'Object Recognition'
                    %turn the color space visuals on
                    app.ColorSpaceDropDown.Visible = 'on';
                    app.ColorSpaceDropDownLabel.Visible = 'on';
                    
                    %turn structuring element visual on
                    app.StructuringElementButtonGroup.Visible = 'on';
                    
                    %turn structuring element size visual off
                    app.StructuringElementSizeButtonGroup.Visible = 'on';
                    
                    %This is the color space switch starting point
                    switch app.colorSpace
                        
                        %color space case 1
                        case 'Red Green Blue'
                            
                            %Turn the rgb value visuals on
                            app.BlueMinimumEditField.Visible = 'on';
                            app.BlueMinimumEditFieldLabel.Visible = 'on';
                            app.GreenMinimumEditField.Visible = 'on';
                            app.GreenMinimumEditFieldLabel.Visible = 'on';
                            app.RedMinimumEditField.Visible = 'on';
                            app.RedMinimumEditFieldLabel.Visible = 'on';
                            app.BlueMaximumEditField.Visible = 'on';
                            app.BlueMaximumEditFieldLabel.Visible = 'on';
                            app.GreenMaximumEditField.Visible = 'on';
                            app.GreenMaximumEditFieldLabel.Visible = 'on';
                            app.RedMaximumEditField.Visible = 'on';
                            app.RedMaximumEditFieldLabel.Visible = 'on';
                            
                            %Turn the hsv value visuals off
                            app.HueMinimumEditFieldLabel.Visible = 'off';
                            app.HueMinimumEditField.Visible = 'off';
                            app.HueMaximumEditFieldLabel.Visible = 'off';
                            app.HueMaximumEditField.Visible = 'off';
                            app.SaturationMinimumEditFieldLabel.Visible = 'off';
                            app.SaturationMinimumEditField.Visible = 'off';
                            app.SaturationMaximumEditFieldLabel.Visible = 'off';
                            app.SaturationMaximumEditField.Visible = 'off';
                            app.ValueMaximumEditFieldLabel.Visible = 'off';
                            app.ValueMaximumEditField.Visible = 'off';
                            app.ValueMinimumEditFieldLabel.Visible = 'off';
                            app.ValueMinimumEditField.Visible = 'off';
                            
                        %color space case 2
                        case 'Hue Saturation Value'
                            
                            %Turn the rgb value visuals off
                            app.BlueMinimumEditField.Visible = 'off';
                            app.BlueMinimumEditFieldLabel.Visible = 'off';
                            app.GreenMinimumEditField.Visible = 'off';
                            app.GreenMinimumEditFieldLabel.Visible = 'off';
                            app.RedMinimumEditField.Visible = 'off';
                            app.RedMinimumEditFieldLabel.Visible = 'off';
                            app.BlueMaximumEditField.Visible = 'off';
                            app.BlueMaximumEditFieldLabel.Visible = 'off';
                            app.GreenMaximumEditField.Visible = 'off';
                            app.GreenMaximumEditFieldLabel.Visible = 'off';
                            app.RedMaximumEditField.Visible = 'off';
                            app.RedMaximumEditFieldLabel.Visible = 'off';
                            
                            %Turn the hsv value visuals on
                            app.HueMinimumEditFieldLabel.Visible = 'on';
                            app.HueMinimumEditField.Visible = 'on';
                            app.HueMaximumEditFieldLabel.Visible = 'on';
                            app.HueMaximumEditField.Visible = 'on';
                            app.SaturationMinimumEditFieldLabel.Visible = 'on';
                            app.SaturationMinimumEditField.Visible = 'on';
                            app.SaturationMaximumEditFieldLabel.Visible = 'on';
                            app.SaturationMaximumEditField.Visible = 'on';
                            app.ValueMaximumEditFieldLabel.Visible = 'on';
                            app.ValueMaximumEditField.Visible = 'on';
                            app.ValueMinimumEditFieldLabel.Visible = 'on';
                            app.ValueMinimumEditField.Visible = 'on';
                            
                    end%color space switch ends
                    
                    f = msgbox('Choose "Generate Preview" to continue','Attention!');
                    
            end%filter option switch ends
            
        end

        % Value changed function: ColorSpaceDropDown
        function ColorSpaceDropDownValueChanged(app, event)
            %sets the color space property
            app.colorSpace = app.ColorSpaceDropDown.Value;
                
            %This is the color space switch starting point
            switch app.colorSpace
                
                %color space case 1
                case 'Red Green Blue'
                    
                    %Turn the rgb value visuals on
                    app.BlueMinimumEditField.Visible = 'on';
                    app.BlueMinimumEditFieldLabel.Visible = 'on';
                    app.GreenMinimumEditField.Visible = 'on';
                    app.GreenMinimumEditFieldLabel.Visible = 'on';
                    app.RedMinimumEditField.Visible = 'on';
                    app.RedMinimumEditFieldLabel.Visible = 'on';
                    app.BlueMaximumEditField.Visible = 'on';
                    app.BlueMaximumEditFieldLabel.Visible = 'on';
                    app.GreenMaximumEditField.Visible = 'on';
                    app.GreenMaximumEditFieldLabel.Visible = 'on';
                    app.RedMaximumEditField.Visible = 'on';
                    app.RedMaximumEditFieldLabel.Visible = 'on';
                    
                    %Turn the hsv value selectors off
                    app.HueMinimumEditFieldLabel.Visible = 'off';
                    app.HueMinimumEditField.Visible = 'off';
                    app.HueMaximumEditFieldLabel.Visible = 'off';
                    app.HueMaximumEditField.Visible = 'off';
                    app.SaturationMinimumEditFieldLabel.Visible = 'off';
                    app.SaturationMinimumEditField.Visible = 'off';
                    app.SaturationMaximumEditFieldLabel.Visible = 'off';
                    app.SaturationMaximumEditField.Visible = 'off';
                    app.ValueMaximumEditFieldLabel.Visible = 'off';
                    app.ValueMaximumEditField.Visible = 'off';
                    app.ValueMinimumEditFieldLabel.Visible = 'off';
                    app.ValueMinimumEditField.Visible = 'off';
                
                %color space case 2
                case 'Hue Saturation Value'
                    
                    %Turn the rgb value visuals off
                    app.BlueMinimumEditField.Visible = 'off';
                    app.BlueMinimumEditFieldLabel.Visible = 'off';
                    app.GreenMinimumEditField.Visible = 'off';
                    app.GreenMinimumEditFieldLabel.Visible = 'off';
                    app.RedMinimumEditField.Visible = 'off';
                    app.RedMinimumEditFieldLabel.Visible = 'off';
                    app.BlueMaximumEditField.Visible = 'off';
                    app.BlueMaximumEditFieldLabel.Visible = 'off';
                    app.GreenMaximumEditField.Visible = 'off';
                    app.GreenMaximumEditFieldLabel.Visible = 'off';
                    app.RedMaximumEditField.Visible = 'off';
                    app.RedMaximumEditFieldLabel.Visible = 'off';
                    
                    %Turn the hsv value selectors on
                    app.HueMinimumEditFieldLabel.Visible = 'on';
                    app.HueMinimumEditField.Visible = 'on';
                    app.HueMaximumEditFieldLabel.Visible = 'on';
                    app.HueMaximumEditField.Visible = 'on';
                    app.SaturationMinimumEditFieldLabel.Visible = 'on';
                    app.SaturationMinimumEditField.Visible = 'on';
                    app.SaturationMaximumEditFieldLabel.Visible = 'on';
                    app.SaturationMaximumEditField.Visible = 'on';
                    app.ValueMaximumEditFieldLabel.Visible = 'on';
                    app.ValueMaximumEditField.Visible = 'on';
                    app.ValueMinimumEditFieldLabel.Visible = 'on';
                    app.ValueMinimumEditField.Visible = 'on';
            
            end%color space switch ends
            
        end

        % Selection changed function: 
        % StructuringElementSizeButtonGroup
        function StructuringElementSizeButtonGroupSelectionChanged(app, event)
            %set the selected structuring element size
            selectedButton = app.StructuringElementSizeButtonGroup.SelectedObject;
            %This is the structuring element size switch starting point
                switch selectedButton
                    
                    %structuring element size case 1
                    case app.SmallButton
                        app.squareStructSize = 5;
                        app.rectangleStructSize = [5 10];
                        app.circleStructSize = 4;
                        
                    %structuring element size case 2
                    case app.MediumButton
                        app.squareStructSize = 13;
                        app.rectangleStructSize = [10 20]; 
                        app.circleStructSize = 10;
                        
                    %structuring element size case 3
                    case app.LargeButton
                        app.squareStructSize = 20;
                        app.rectangleStructSize = [15 30];
                        app.circleStructSize = 18;
                        
                end%structuring element size switch ends
        end

        % Selection changed function: StructuringElementButtonGroup
        function StructuringElementButtonGroupSelectionChanged(app, event)
            app.structEl = app.StructuringElementButtonGroup.SelectedObject;
        end

        % Callback function: SaveMenu, SaveResultButton
        function SaveResultButtonPushed(app, event)
            %Reads the file in with the following file types
            [app.fileName] = uiputfile( ...
                {'*.png;*.jpg;*.bmp',...
                'Image Files (*.png;*.jpg;*.bmp)';
                '*.*',  'All Files (*.*)'}, ...
                'Select an Image');
            
            %Display an error if the file can't be read
            if app.fileName==0
                message = sprintf('Sorry! \n There was an error.');
                uialert(errfig,message,'Error',...
                    'Icon','warning');
            
            %The file has succesfully been read
            else
                imwrite(app.imageData,app.fileName);
                
            end%ifelse ends
            
        end

        % Value changed function: GammaLowEditField
        function GammaLowEditFieldValueChanged(app, event)
            app.gammaLow = app.GammaLowEditField.Value;
            
        end

        % Value changed function: GammaHighEditField
        function GammaHighEditFieldValueChanged(app, event)
            app.gammaHigh = app.GammaHighEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FinalProjectCadenSandersUIFigure = uifigure;
            app.FinalProjectCadenSandersUIFigure.Name = 'Final Project - Caden Sanders';
            app.FileMenu = uimenu(app.FinalProjectCadenSandersUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveMenu.Text = 'Save';
            app.EditMenu = uimenu(app.FinalProjectCadenSandersUIFigure);
            app.EditMenu.Text = 'Edit';
            app.OpenImageButton = uibutton(app.FinalProjectCadenSandersUIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.TabGroup = uitabgroup(app.FinalProjectCadenSandersUIFigure);
            app.Image1Tab = uitab(app.TabGroup);
            app.Image1Tab.Title = 'Image 1';
            app.UIAxes = uiaxes(app.Image1Tab);
            title(app.UIAxes, 'Image 1 Current')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.TabGroup_2 = uitabgroup(app.FinalProjectCadenSandersUIFigure);
            app.Image1Tab_2 = uitab(app.TabGroup_2);
            app.Image1Tab_2.Title = 'Image 1';
            app.UIAxes_2 = uiaxes(app.Image1Tab_2);
            title(app.UIAxes_2, 'Image 1 Preview')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.CurrentLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.CurrentLabel.Text = 'Current';
            app.PreviewLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.PreviewLabel.Text = 'Preview';
            app.SaveResultButton = uibutton(app.FinalProjectCadenSandersUIFigure, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultButton.Text = 'Save Result';
            app.GeneratePreviewButton = uibutton(app.FinalProjectCadenSandersUIFigure, 'push');
            app.GeneratePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePreviewButtonPushed, true);
            app.GeneratePreviewButton.Text = 'Generate Preview';
            app.ApplyChangesButton = uibutton(app.FinalProjectCadenSandersUIFigure, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.SpatialDomainFilterOptionsDropDownLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.SpatialDomainFilterOptionsDropDownLabel.Text = 'Spatial Domain Filter Options';
            app.SpatialDomainFilterOptionsDropDown = uidropdown(app.FinalProjectCadenSandersUIFigure);
            app.SpatialDomainFilterOptionsDropDown.Items = {'None', 'Brightness', 'Contrast', 'Smoothing Low-Pass', 'Edge Detect High-Pass', 'Highboost', 'Global Histogram-Equalization', 'Adaptive Histogram-Equalization'};
            app.SpatialDomainFilterOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @SpatialDomainFilterOptionsDropDownValueChanged, true);
            app.SpatialDomainFilterOptionsDropDown.Value = 'None';
            app.NeighborhoodSizeButtonGroup = uibuttongroup(app.FinalProjectCadenSandersUIFigure);
            app.NeighborhoodSizeButtonGroup.Title = 'Neighborhood Size';
            app.NeighborhoodSizeButtonGroup.Visible = 'off';
            app.x3Button = uiradiobutton(app.NeighborhoodSizeButtonGroup);
            app.x3Button.Text = '3x3';
            app.x3Button.Value = true;
            app.x5Button = uiradiobutton(app.NeighborhoodSizeButtonGroup);
            app.x5Button.Text = '5x5';
            app.x7Button = uiradiobutton(app.NeighborhoodSizeButtonGroup);
            app.x7Button.Text = '7x7';
            app.x9Button = uiradiobutton(app.NeighborhoodSizeButtonGroup);
            app.x9Button.Text = '9x9';
            app.BrightnessSliderLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.BrightnessSliderLabel.Visible = 'off';
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.FinalProjectCadenSandersUIFigure);
            app.BrightnessSlider.Limits = [0 0.99];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.Visible = 'off';
            app.ContrastSliderLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.ContrastSliderLabel.Visible = 'off';
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.FinalProjectCadenSandersUIFigure);
            app.ContrastSlider.Limits = [0 0.99];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Visible = 'off';
            app.FrequencyDomainFilterOptionsDropDownLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.FrequencyDomainFilterOptionsDropDownLabel.Visible = 'off';
            app.FrequencyDomainFilterOptionsDropDownLabel.Text = 'Frequency Domain Filter Options';
            app.FrequencyDomainFilterOptionsDropDown = uidropdown(app.FinalProjectCadenSandersUIFigure);
            app.FrequencyDomainFilterOptionsDropDown.Items = {'None', 'Low-Pass', 'High-Pass', 'Highboost', 'Band-Pass', 'Band-Stop', 'Homomorphic'};
            app.FrequencyDomainFilterOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyDomainFilterOptionsDropDownValueChanged, true);
            app.FrequencyDomainFilterOptionsDropDown.Visible = 'off';
            app.FrequencyDomainFilterOptionsDropDown.Value = 'None';
            app.FilterDomainButtonGroup = uibuttongroup(app.FinalProjectCadenSandersUIFigure);
            app.FilterDomainButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterDomainButtonGroupSelectionChanged, true);
            app.FilterDomainButtonGroup.Title = 'Filter Domain';
            app.SpatialDomainButton = uiradiobutton(app.FilterDomainButtonGroup);
            app.SpatialDomainButton.Text = 'Spatial Domain';
            app.SpatialDomainButton.Value = true;
            app.FrequencyDomainButton = uiradiobutton(app.FilterDomainButtonGroup);
            app.FrequencyDomainButton.Text = 'Frequency Domain';
            app.MorphologicalFiltersButton = uiradiobutton(app.FilterDomainButtonGroup);
            app.MorphologicalFiltersButton.Text = 'Morphological Filters';
            app.BoostScalingCoefficientEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.BoostScalingCoefficientEditFieldLabel.Visible = 'off';
            app.BoostScalingCoefficientEditFieldLabel.Text = 'Boost Scaling Coefficient';
            app.BoostScalingCoefficientEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.BoostScalingCoefficientEditField.Limits = [1 3];
            app.BoostScalingCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @BoostScalingCoefficientEditFieldValueChanged, true);
            app.BoostScalingCoefficientEditField.Visible = 'off';
            app.BoostScalingCoefficientEditField.Value = 1;
            app.FilterTypeDropDownLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.FilterTypeDropDownLabel.Visible = 'off';
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.FinalProjectCadenSandersUIFigure);
            app.FilterTypeDropDown.Items = {'None', 'Ideal', 'Gaussian', 'Butterworth'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Visible = 'off';
            app.FilterTypeDropDown.Value = 'None';
            app.ButterworthFilterOrderDropDownLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.ButterworthFilterOrderDropDownLabel.Visible = 'off';
            app.ButterworthFilterOrderDropDownLabel.Text = 'Butterworth Filter Order';
            app.ButterworthFilterOrderDropDown = uidropdown(app.FinalProjectCadenSandersUIFigure);
            app.ButterworthFilterOrderDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.ButterworthFilterOrderDropDown.ValueChangedFcn = createCallbackFcn(app, @ButterworthFilterOrderDropDownValueChanged, true);
            app.ButterworthFilterOrderDropDown.Visible = 'off';
            app.ButterworthFilterOrderDropDown.Value = '1';
            app.FirstCutoffFrequencyEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.FirstCutoffFrequencyEditFieldLabel.Visible = 'off';
            app.FirstCutoffFrequencyEditFieldLabel.Text = 'First Cutoff Frequency';
            app.FirstCutoffFrequencyEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.FirstCutoffFrequencyEditField.Limits = [1e-05 100];
            app.FirstCutoffFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FirstCutoffFrequencyEditFieldValueChanged, true);
            app.FirstCutoffFrequencyEditField.Visible = 'off';
            app.FirstCutoffFrequencyEditField.Value = 3;
            app.SecondCutoffFrequencyEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.SecondCutoffFrequencyEditFieldLabel.Visible = 'off';
            app.SecondCutoffFrequencyEditFieldLabel.Text = 'Second Cutoff Frequency';
            app.SecondCutoffFrequencyEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.SecondCutoffFrequencyEditField.Limits = [0.0001 100];
            app.SecondCutoffFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SecondCutoffFrequencyEditFieldValueChanged, true);
            app.SecondCutoffFrequencyEditField.Visible = 'off';
            app.SecondCutoffFrequencyEditField.Value = 3;
            app.MorphologicalFilterOptionsLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.MorphologicalFilterOptionsLabel.Visible = 'off';
            app.MorphologicalFilterOptionsLabel.Text = 'Morphological Filter Options';
            app.MorphilogicalFilterOptionsDropDown = uidropdown(app.FinalProjectCadenSandersUIFigure);
            app.MorphilogicalFilterOptionsDropDown.Items = {'None', 'Generate Mask', 'Erosion', 'Dilation', 'Opening', 'Closing', 'Boundary (Beta)', 'Object Recognition'};
            app.MorphilogicalFilterOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @MorphilogicalFilterOptionsDropDownValueChanged, true);
            app.MorphilogicalFilterOptionsDropDown.Visible = 'off';
            app.MorphilogicalFilterOptionsDropDown.Value = 'None';
            app.ColorSpaceDropDownLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.ColorSpaceDropDownLabel.Visible = 'off';
            app.ColorSpaceDropDownLabel.Text = 'Color Space';
            app.ColorSpaceDropDown = uidropdown(app.FinalProjectCadenSandersUIFigure);
            app.ColorSpaceDropDown.Items = {'Red Green Blue', 'Hue Saturation Value'};
            app.ColorSpaceDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorSpaceDropDownValueChanged, true);
            app.ColorSpaceDropDown.Visible = 'off';
            app.ColorSpaceDropDown.Value = 'Red Green Blue';
            app.RedMinimumEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.RedMinimumEditFieldLabel.Visible = 'off';
            app.RedMinimumEditFieldLabel.Text = 'Red Minimum';
            app.RedMinimumEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.RedMinimumEditField.Limits = [0 255];
            app.RedMinimumEditField.Visible = 'off';
            app.RedMaximumEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.RedMaximumEditFieldLabel.Visible = 'off';
            app.RedMaximumEditFieldLabel.Text = 'Red Maximum';
            app.RedMaximumEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.RedMaximumEditField.Limits = [0 255];
            app.RedMaximumEditField.Visible = 'off';
            app.RedMaximumEditField.Value = 255;
            app.GreenMinimumEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.GreenMinimumEditFieldLabel.Visible = 'off';
            app.GreenMinimumEditFieldLabel.Text = 'Green Minimum';
            app.GreenMinimumEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.GreenMinimumEditField.Limits = [0 255];
            app.GreenMinimumEditField.Visible = 'off';
            app.GreenMaximumEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.GreenMaximumEditFieldLabel.Visible = 'off';
            app.GreenMaximumEditFieldLabel.Text = 'Green Maximum';
            app.GreenMaximumEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.GreenMaximumEditField.Limits = [0 255];
            app.GreenMaximumEditField.Visible = 'off';
            app.GreenMaximumEditField.Value = 255;
            app.BlueMinimumEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.BlueMinimumEditFieldLabel.Visible = 'off';
            app.BlueMinimumEditFieldLabel.Text = 'Blue Minimum';
            app.BlueMinimumEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.BlueMinimumEditField.Limits = [0 255];
            app.BlueMinimumEditField.Visible = 'off';
            app.BlueMaximumEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.BlueMaximumEditFieldLabel.Visible = 'off';
            app.BlueMaximumEditFieldLabel.Text = 'Blue Maximum';
            app.BlueMaximumEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.BlueMaximumEditField.Limits = [0 255];
            app.BlueMaximumEditField.Visible = 'off';
            app.BlueMaximumEditField.Value = 255;
            app.HueMinimumEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.HueMinimumEditFieldLabel.Visible = 'off';
            app.HueMinimumEditFieldLabel.Text = 'Hue Minimum';
            app.HueMinimumEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.HueMinimumEditField.Limits = [0 1];
            app.HueMinimumEditField.Visible = 'off';
            app.HueMaximumEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.HueMaximumEditFieldLabel.Visible = 'off';
            app.HueMaximumEditFieldLabel.Text = 'Hue Maximum';
            app.HueMaximumEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.HueMaximumEditField.Limits = [0 1];
            app.HueMaximumEditField.Visible = 'off';
            app.HueMaximumEditField.Value = 1;
            app.SaturationMinimumEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.SaturationMinimumEditFieldLabel.Visible = 'off';
            app.SaturationMinimumEditFieldLabel.Text = 'Saturation Minimum';
            app.SaturationMinimumEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.SaturationMinimumEditField.Limits = [0 1];
            app.SaturationMinimumEditField.Visible = 'off';
            app.SaturationMaximumEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.SaturationMaximumEditFieldLabel.Visible = 'off';
            app.SaturationMaximumEditFieldLabel.Text = 'Saturation Maximum';
            app.SaturationMaximumEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.SaturationMaximumEditField.Limits = [0 1];
            app.SaturationMaximumEditField.Visible = 'off';
            app.SaturationMaximumEditField.Value = 0.5;
            app.ValueMaximumEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.ValueMaximumEditFieldLabel.Visible = 'off';
            app.ValueMaximumEditFieldLabel.Text = 'Value Maximum';
            app.ValueMaximumEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.ValueMaximumEditField.Limits = [0 1];
            app.ValueMaximumEditField.Visible = 'off';
            app.ValueMaximumEditField.Value = 1;
            app.ValueMinimumEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.ValueMinimumEditFieldLabel.Visible = 'off';
            app.ValueMinimumEditFieldLabel.Text = 'Value Minimum';
            app.ValueMinimumEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.ValueMinimumEditField.Limits = [0 1];
            app.ValueMinimumEditField.Visible = 'off';
            app.StructuringElementButtonGroup = uibuttongroup(app.FinalProjectCadenSandersUIFigure);
            app.StructuringElementButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StructuringElementButtonGroupSelectionChanged, true);
            app.StructuringElementButtonGroup.Title = 'Structuring Element';
            app.StructuringElementButtonGroup.Visible = 'off';
            app.SquareButton = uiradiobutton(app.StructuringElementButtonGroup);
            app.SquareButton.Text = 'Square';
            app.SquareButton.Value = true;
            app.RectangleButton = uiradiobutton(app.StructuringElementButtonGroup);
            app.RectangleButton.Text = 'Rectangle';
            app.CircleDiskButton = uiradiobutton(app.StructuringElementButtonGroup);
            app.CircleDiskButton.Text = 'Circle/Disk';
            app.StructuringElementSizeButtonGroup = uibuttongroup(app.FinalProjectCadenSandersUIFigure);
            app.StructuringElementSizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StructuringElementSizeButtonGroupSelectionChanged, true);
            app.StructuringElementSizeButtonGroup.Title = 'Structuring Element Size';
            app.StructuringElementSizeButtonGroup.Visible = 'off';
            app.SmallButton = uitogglebutton(app.StructuringElementSizeButtonGroup);
            app.SmallButton.Text = 'Small';
            app.SmallButton.Value = true;
            app.MediumButton = uitogglebutton(app.StructuringElementSizeButtonGroup);
            app.MediumButton.Text = 'Medium';
            app.LargeButton = uitogglebutton(app.StructuringElementSizeButtonGroup);
            app.LargeButton.Text = 'Large';
            app.GammaLowEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.GammaLowEditFieldLabel.Visible = 'off';
            app.GammaLowEditFieldLabel.Text = 'Gamma Low';
            app.GammaLowEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.GammaLowEditField.Limits = [0 Inf];
            app.GammaLowEditField.ValueChangedFcn = createCallbackFcn(app, @GammaLowEditFieldValueChanged, true);
            app.GammaLowEditField.Visible = 'off';
            app.GammaHighEditFieldLabel = uilabel(app.FinalProjectCadenSandersUIFigure);
            app.GammaHighEditFieldLabel.Visible = 'off';
            app.GammaHighEditFieldLabel.Text = 'Gamma High';
            app.GammaHighEditField = uieditfield(app.FinalProjectCadenSandersUIFigure, 'numeric');
            app.GammaHighEditField.Limits = [0 Inf];
            app.GammaHighEditField.ValueChangedFcn = createCallbackFcn(app, @GammaHighEditFieldValueChanged, true);
            app.GammaHighEditField.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = project2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FinalProjectCadenSandersUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FinalProjectCadenSandersUIFigure)
        end
    end
end